classdef dImaproce < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
        Menu_5       matlab.ui.container.Menu
        Menu_6       matlab.ui.container.Menu
        Menu_7       matlab.ui.container.Menu
        Menu_11      matlab.ui.container.Menu
        Menu_14      matlab.ui.container.Menu
        Menu_15      matlab.ui.container.Menu
        Menu_16      matlab.ui.container.Menu
        Menu_17      matlab.ui.container.Menu
        RobertsMenu  matlab.ui.container.Menu
        Menu_19      matlab.ui.container.Menu
        Menu_8       matlab.ui.container.Menu
        Menu_3       matlab.ui.container.Menu
        Menu_10      matlab.ui.container.Menu
        RightPanel   matlab.ui.container.Panel
        EditField_3  matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        EditField_2  matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        Hyperlink    matlab.ui.control.Hyperlink
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        UIAxes_2     matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ParameterToPass; % Description
        selectedImage;
        Imagray;
        Imadb;
        Imaim2bw;
        binaryImage;
        openedImage;
        Imagausf;
        labeledImage;
        Imagf;
        sumOf50s=0;
        sumOf10s=0;
        H;
        ImaTemp;

    end
    
    methods (Access = private)
        
        function results = funcRGB2gray(app)
            app.Imagray = rgb2gray(app.selectedImage);
            results = app.Imagray;
        end
        
        function results = funcImaim2bw(app)
            app.Imadb = im2double(app.Imagray);
                    
            T0 = 0.01;
            T1 = (min(app.Imadb(:)) + max(app.Imadb(:)))/2;
            r1 = find(app.Imadb > T1);
            r2 = find(app.Imadb <= T1);
            T2 = (mean(app.Imadb(r1)) + mean(app.Imadb(r2)))/2;
            while abs(T2 -T1) < T0
                T1 = T2;
                r1 = find(app.Imadb > T1);
                r2 = find(app.Imadb <= T1);
                T2 = (mean(app.Imadb(r1)) + mean(app.Imadb(r2)))/2;
            end
            
            app.Imaim2bw = imbinarize(app.Imadb, T2);

            results = app.Imaim2bw;
        end

        function results = funcImopen(app)
            app.binaryImage = imbinarize(app.Imagray);
            
            % 定义开运算的结构元素
            se = strel('disk',32);  % 这里使用了一个半径为5的圆形结构元素，你可以根据需要调整大小
            
            % 应用开运算
            app.openedImage = imopen(app.binaryImage, se);
            
            results = app.openedImage;
        end

        function results = funcImagausf(app)
            % 二值化图像（可以使用适当的阈值或其他方法）
            sigma=8;                                  % 标准差大小  
            window=double(uint8(3*sigma)*2+1);        % 窗口大小一半为3*sigma  
            app.H=fspecial('gaussian', window, sigma);    % fspecial('gaussian', hsize, sigma)产生滤波模板   
            
            app.Imagausf = app.openedImage;
            LoopNum = 0;
            while LoopNum < 5
                app.Imagausf = imfilter(app.Imagausf,app.H,'replicate');
                LoopNum = LoopNum + 1;
            end
            
            results = app.Imagausf;
        end
        
        function results = funcLabeledImage(app)
            [app.labeledImage,~] = bwlabel(~app.Imagausf, 4);

            results = app.labeledImage;
        end
        
        function results = funcImagf(app)
            [app.Imagf, ~] = edge(app.Imadb,'roberts', 16/255);
            results = app.Imagf;
        end
        
        function results = funcHoughCir(app)
            %Find Circles (Use Hough Transform)                     %%%%%%%%%%%%%%%%%%%%↓↓↓↓↓↓↓↓↓↓↓%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [centers, radii] = find_circles(app.Imagf, [110, 125]); %%%%%%%%%%%%%%%%%%%%此处修改参数%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                                                    %%%%%%%%%%%%%%%%%%%%↑↑↑↑↑↑↑↑↑↑↑%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Remove overlapped circles
            [centersNew,radiiNew] = RemoveOverLap(centers,radii,125,1);
            
            % MatA = [centersNew,radiiNew];
            %Draw
            % hough_circles_draw(app.UIAxes, centersNew, radiiNew);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % %Find Circles (Use Hough Transform)
            % [centers, radii] = find_circles(app.Imagf, [120, 130]);
            % 
            % %Remove overlapped circles
            % [centersNew,radiiNew] = RemoveOverLap(centers,radii,100,1);
            % Draw

            hough_circles_draw(app.selectedImage, centersNew, radiiNew);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %Total coins calculation


            for i = 1 : size(radiiNew)     
                    if(radiiNew(i) > 118)
                      app.sumOf50s=app.sumOf50s+1;
                    else
                      app.sumOf10s=app.sumOf10s+1;
                    end          
            end
            results = 0;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Allow user to select an image file
            [filename, filepath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files'}, 'Select Image');
    
            % Check if the user selected a file
            if isequal(filename, 0)
                return; % User canceled the file selection
            end
    
            % Construct the full file path
            ImagePath = fullfile(filepath, filename);
    
            % Read the selected image
            app.selectedImage = imread(ImagePath);
    
            % Display the image in the Axes component
            imshow(app.selectedImage, 'Parent', app.UIAxes);

            % Display image path in EditField
            app.EditField.Value = ImagePath;
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            % Allow user to select an image file
            [filename, filepath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files'}, 'Select Image');
    
            % Check if the user selected a file
            if isequal(filename, 0)
                return; % User canceled the file selection
            end
    
            % Construct the full file path
            ImagePath = fullfile(filepath, filename);
    
            % Read the selected image
            app.selectedImage = imread(ImagePath);
    
            % Display the image in the Axes component
            imshow(app.selectedImage, 'Parent', app.UIAxes);

            % Display image path in EditField
            app.EditField.Value = ImagePath;
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            % Close the application
            delete('..\Image\temPicture20231212.png'); % 删除指定的图片文件
            delete(app);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            % 检查是否存在打开的图像
            if ~isempty(app.UIAxes)
                % 关闭打开的图像
                cla(app.UIAxes); % 清空 UIAxes
                app.UIAxes = uiaxes(app.RightPanel);
                title(app.UIAxes, '  ')
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.ZTick = [];
                app.UIAxes.Box = 'on';
                app.UIAxes.Position = [70 240 250 250];
                app.EditField.Value = '';
            else
                % 提示用户没有打开的图像
                uialert(app.UIFigure, '没有打开的图片。', '提示', 'Icon', 'warning');
            end

            if ~isempty(app.UIAxes_2)
                % 关闭打开的图像
                cla(app.UIAxes_2); % 清空 UIAxes
                app.UIAxes = uiaxes(app.RightPanel);
                title(app.UIAxes, '  ')
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.ZTick = [];
                app.UIAxes.Box = 'on';
                app.UIAxes.Position = [384 130 360 360];
                app.EditField.Value = '';
            else
                % 提示用户没有打开的图像
                uialert(app.UIFigure, '没有打开的图片。', '提示', 'Icon', 'warning');
            end
            
            delete('..\Image\temPicture20231212.png'); % 删除指定的图片文件
            
            
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            str_help = ['Click "选择图片", select the image where you want to obtain the number and amount of coins, ' ...
                'select coin detection and counting in "选择", and finally click "开始执行". ' ...
                'You can choose the save format in "文件","另存为".'];
            msgbox(str_help,'提示信息');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
            try
                img = imread(value);
                imshow(img, 'Parent', app.UIAxes);
            catch
                % 处理读取图像失败的情况
                errordlg('无法读取图像。请检查文件路径。', '错误');
            end
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
                % --- Executes on button press in saveImageButton.
            % 获取用户选择的保存路径和文件名
            [filename, pathname] = uiputfile({'*.png';'*.jpg';'*.bmp'}, 'Save Image As');
        
            % 如果用户取消了保存操作，filename 和 pathname 将为 0
            if isequal(filename,0) || isequal(pathname,0)
                % 用户取消保存操作
                return;
            end
        
            % 构建完整的保存路径
            fullFilePath = fullfile(pathname, filename);
        
            % 从 axes 控件中获取图像数据
            imageData = imread('..\Image\temPicture20231212.png');
        
            % 保存图像到指定文件
            imwrite(imageData, fullFilePath);
        
            % 显示保存成功的消息
            msgbox('Image saved successfully!', 'Success', 'modal');

        end

        % Menu selected function: Menu_14
        function Menu_14Selected(app, event)
           app.ParameterToPass = 'rgb2gray';
        end

        % Menu selected function: Menu_15
        function Menu_15Selected(app, event)
            app.ParameterToPass = 'imbinarize';
        end

        % Menu selected function: Menu_16
        function Menu_16Selected(app, event)
            app.ParameterToPass = 'imopen';
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
            app.ParameterToPass = 'bwlable';
        end

        % Menu selected function: RobertsMenu
        function RobertsMenuSelected(app, event)
            app.ParameterToPass = 'edge';
        end

        % Menu selected function: Menu_19
        function Menu_19Selected(app, event)
            app.ParameterToPass = 'houghCir';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if strcmp(app.ParameterToPass, 'rgb2gray')
                funcRGB2gray(app)
                imshow(app.Imagray, 'Parent', app.UIAxes_2);

            elseif strcmp(app.ParameterToPass, 'imbinarize')
                funcRGB2gray(app);
                funcImaim2bw(app);
                funcImopen(app);
                imshow(app.Imaim2bw, 'Parent', app.UIAxes_2);

            elseif strcmp(app.ParameterToPass, 'imopen')
                funcRGB2gray(app);
                funcImaim2bw(app);
                funcImopen(app);
                funcImagausf(app);
                imshow(app.openedImage, 'Parent', app.UIAxes_2);

            elseif strcmp(app.ParameterToPass, 'bwlable')
                funcRGB2gray(app);
                funcImaim2bw(app);
                funcImopen(app);
                funcImagausf(app);
                funcLabeledImage(app);
                imshow(label2rgb(app.labeledImage), 'Parent', app.UIAxes_2);

            elseif strcmp(app.ParameterToPass, 'edge')
                funcRGB2gray(app);
                funcImaim2bw(app);
                funcImopen(app);
                funcImagausf(app);
                funcLabeledImage(app);
                funcImagf(app);
                imshow(app.Imagf, 'Parent', app.UIAxes_2);

            elseif strcmp(app.ParameterToPass, 'houghCir')
                funcRGB2gray(app);
                funcImaim2bw(app);
                funcImopen(app);
                funcImagausf(app);
                funcLabeledImage(app);
                funcImagf(app);
                funcHoughCir(app);
                imshow(imread('..\Image\temPicture20231212.png'), 'Parent', app.UIAxes_2);
                

                Total = app.sumOf10s+app.sumOf50s ;

                app.EditField_2.Value = num2str(Total)+" 个";
                app.EditField_3.Value = "一元硬币有"+num2str(app.sumOf50s)+"个，五角硬币有"+num2str(app.sumOf10s)+"个。";
                else
                
            end
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            funcRGB2gray(app);
            funcImaim2bw(app);
            funcImopen(app);
            funcImagausf(app);
            funcLabeledImage(app);
            funcImagf(app);
            funcHoughCir(app);
            imshow(imread('..\Image\temPicture20231212.png'), 'Parent', app.UIAxes_2);

            Total = app.sumOf10s+app.sumOf50s ;

            app.EditField_2.Value = num2str(Total)+" 个";
            app.EditField_3.Value = "一元硬币有"+num2str(app.sumOf50s)+"个，五角硬币有"+num2str(app.sumOf10s)+"个。";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '打开';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '关闭';
            app.Menu_6 = uimenu(app.Menu);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '另存为';
            app.Menu_7 = uimenu(app.Menu);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '退出';
            app.Menu_11 = uimenu(app.UIFigure);
            app.Menu_11.Text = '编辑';
            app.Menu_14 = uimenu(app.Menu_11);
            app.Menu_14.MenuSelectedFcn = createCallbackFcn(app, @Menu_14Selected, true);
            app.Menu_14.Text = '彩色图像转灰度图像';
            app.Menu_15 = uimenu(app.Menu_11);
            app.Menu_15.MenuSelectedFcn = createCallbackFcn(app, @Menu_15Selected, true);
            app.Menu_15.Text = '迭代式阈值分割';
            app.Menu_16 = uimenu(app.Menu_11);
            app.Menu_16.MenuSelectedFcn = createCallbackFcn(app, @Menu_16Selected, true);
            app.Menu_16.Text = '二值图像开运算';
            app.Menu_17 = uimenu(app.Menu_11);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Text = '标记连通域';
            app.RobertsMenu = uimenu(app.Menu_11);
            app.RobertsMenu.MenuSelectedFcn = createCallbackFcn(app, @RobertsMenuSelected, true);
            app.RobertsMenu.Text = '边缘检测（Roberts算子）';
            app.Menu_19 = uimenu(app.Menu_11);
            app.Menu_19.MenuSelectedFcn = createCallbackFcn(app, @Menu_19Selected, true);
            app.Menu_19.Text = '霍夫变换圆检测';
            app.Menu_8 = uimenu(app.UIFigure);
            app.Menu_8.Text = '选择';
            app.Menu_3 = uimenu(app.Menu_8);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '硬币检测及计数';
            app.Menu_10 = uimenu(app.UIFigure);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '帮助';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '原图')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, '执行后')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择图片';
            app.Button_2 = uibutton(app.RightPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '开始执行';
            app.Hyperlink = uihyperlink(app.RightPanel);
            app.Hyperlink.URL = 'https://github.com/Hina-15';
            app.Hyperlink.Text = 'Learn more';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '图片路径';
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '硬币数量';
            app.EditField_2 = uieditfield(app.RightPanel, 'text');
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '硬币种类';
            app.EditField_3 = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dImaproce

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end