classdef dImaproce < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
        Menu_5       matlab.ui.container.Menu
        Menu_6       matlab.ui.container.Menu
        Menu_7       matlab.ui.container.Menu
        Menu_11      matlab.ui.container.Menu
        Menu5        matlab.ui.container.Menu
        Menu_13      matlab.ui.container.Menu
        Menu2        matlab.ui.container.Menu
        Menu3        matlab.ui.container.Menu
        Menu4        matlab.ui.container.Menu
        Menu6        matlab.ui.container.Menu
        Menu_8       matlab.ui.container.Menu
        Menu_3       matlab.ui.container.Menu
        Menu_10      matlab.ui.container.Menu
        RightPanel   matlab.ui.container.Panel
        EditField_3  matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        EditField_2  matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        Hyperlink    matlab.ui.control.Hyperlink
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        UIAxes_2     matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Allow user to select an image file
            [filename, filepath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files'}, 'Select Image');
    
            % Check if the user selected a file
            if isequal(filename, 0)
                return; % User canceled the file selection
            end
    
            % Construct the full file path
            ImagePath = fullfile(filepath, filename);
    
            % Read the selected image
            selectedImage = imread(ImagePath);
    
            % Display the image in the Axes component
            imshow(selectedImage, 'Parent', app.UIAxes);

            % Display image path in EditField
            app.EditField.Value = ImagePath;
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            % Allow user to select an image file
            [filename, filepath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files'}, 'Select Image');
    
            % Check if the user selected a file
            if isequal(filename, 0)
                return; % User canceled the file selection
            end
    
            % Construct the full file path
            ImagePath = fullfile(filepath, filename);
    
            % Read the selected image
            selectedImage = imread(ImagePath);
    
            % Display the image in the Axes component
            imshow(selectedImage, 'Parent', app.UIAxes);

            % Display image path in EditField
            app.EditField.Value = ImagePath;
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            % Close the application
            delete(app);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            % 检查是否存在打开的图像
            if ~isempty(app.UIAxes)
                % 关闭打开的图像
                cla(app.UIAxes); % 清空 UIAxes
                app.UIAxes = uiaxes(app.RightPanel);
                title(app.UIAxes, '  ')
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.ZTick = [];
                app.UIAxes.Box = 'on';
                app.UIAxes.Position = [70 240 250 250];
                app.EditField.Value = '';
            else
                % 提示用户没有打开的图像
                uialert(app.UIFigure, '没有打开的图片。', '提示', 'Icon', 'warning');
            end
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            str_help = ['Click "选择图片", select the image where you want to obtain the number and amount of coins, ' ...
                'select coin detection and counting in "选择", and finally click "开始执行". ' ...
                'You can choose the save format in "文件","另存为".'];
            msgbox(str_help,'提示信息');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
            try
                img = imread(value);
                imshow(img, 'Parent', app.UIAxes);
            catch
                % 处理读取图像失败的情况
                errordlg('无法读取图像。请检查文件路径。', '错误');
            end
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
                % --- Executes on button press in saveImageButton.
            % 获取用户选择的保存路径和文件名
            [filename, pathname] = uiputfile({'*.png';'*.jpg';'*.bmp'}, 'Save Image As');
        
            % 如果用户取消了保存操作，filename 和 pathname 将为 0
            if isequal(filename,0) || isequal(pathname,0)
                % 用户取消保存操作
                return;
            end
        
            % 构建完整的保存路径
            fullFilePath = fullfile(pathname, filename);
        
            % 从 axes 控件中获取图像数据
            imageData = app.UIAxes_2.CData;
        
            % 保存图像到指定文件
            imwrite(imageData, fullFilePath);
        
            % 显示保存成功的消息
            msgbox('Image saved successfully!', 'Success', 'modal');

        end

        % Menu selected function: Menu5
        function Menu5Selected(app, event)
           KeyStartButton = 1; 
        end

        % Menu selected function: Menu_13
        function Menu_13Selected(app, event)
            KeyStartButton = 2;
        end

        % Menu selected function: Menu2
        function Menu2Selected(app, event)
            KeyStartButton = 3;
        end

        % Menu selected function: Menu3
        function Menu3Selected(app, event)
            KeyStartButton = 4;
        end

        % Menu selected function: Menu4
        function Menu4Selected(app, event)
            KeyStartButton = 5;
        end

        % Menu selected function: Menu6
        function Menu6Selected(app, event)
            KeyStartButton = 6;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '打开';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '关闭';
            app.Menu_6 = uimenu(app.Menu);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '另存为';
            app.Menu_7 = uimenu(app.Menu);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '退出';
            app.Menu_11 = uimenu(app.UIFigure);
            app.Menu_11.Text = '编辑';
            app.Menu5 = uimenu(app.Menu_11);
            app.Menu5.MenuSelectedFcn = createCallbackFcn(app, @Menu5Selected, true);
            app.Menu5.Text = 'Menu5';
            app.Menu_13 = uimenu(app.Menu_11);
            app.Menu_13.MenuSelectedFcn = createCallbackFcn(app, @Menu_13Selected, true);
            app.Menu_13.Text = 'Menu';
            app.Menu2 = uimenu(app.Menu_11);
            app.Menu2.MenuSelectedFcn = createCallbackFcn(app, @Menu2Selected, true);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.Menu_11);
            app.Menu3.MenuSelectedFcn = createCallbackFcn(app, @Menu3Selected, true);
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.Menu_11);
            app.Menu4.MenuSelectedFcn = createCallbackFcn(app, @Menu4Selected, true);
            app.Menu4.Text = 'Menu4';
            app.Menu6 = uimenu(app.Menu_11);
            app.Menu6.MenuSelectedFcn = createCallbackFcn(app, @Menu6Selected, true);
            app.Menu6.Text = 'Menu6';
            app.Menu_8 = uimenu(app.UIFigure);
            app.Menu_8.Text = '选择';
            app.Menu_3 = uimenu(app.Menu_8);
            app.Menu_3.Text = '硬币检测及计数';
            app.Menu_10 = uimenu(app.UIFigure);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '帮助';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '原图')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, '执行后')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择图片';
            app.Button_2 = uibutton(app.RightPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '开始执行';
            app.Hyperlink = uihyperlink(app.RightPanel);
            app.Hyperlink.URL = 'https://github.com/Hina-15';
            app.Hyperlink.Text = 'Learn more';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '图片路径';
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '硬币数量';
            app.EditField_2 = uieditfield(app.RightPanel, 'text');
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '硬币种类';
            app.EditField_3 = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dImaproce

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end